classdef labTopicosAppV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TrayectoriaLabel            matlab.ui.control.Label
        ControldeRobotSCARALabel    matlab.ui.control.Label
        TiempoTrEditField           matlab.ui.control.NumericEditField
        TiempoEditFieldLabel        matlab.ui.control.Label
        VelocidadmmsEditField       matlab.ui.control.NumericEditField
        VelocidadmmsEditFieldLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        sendTrayectoryButton        matlab.ui.control.Button
        stopMovementButton          matlab.ui.control.Button
        moveDownButton              matlab.ui.control.Button
        moveLeftButton              matlab.ui.control.Button
        moveRightButton             matlab.ui.control.Button
        moveUpButton                matlab.ui.control.Button
        YfinalTrEditField           matlab.ui.control.NumericEditField
        YfinalEditFieldLabel        matlab.ui.control.Label
        XfinalTrEditField           matlab.ui.control.NumericEditField
        XfinalEditFieldLabel        matlab.ui.control.Label
        TipoTrDropDown              matlab.ui.control.DropDown
        TipoDropDownLabel           matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        PosicinTab                  matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        UIAxesCartesian             matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        PosicionesTab               matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        UIAxesPosiciones            matlab.ui.control.UIAxes
        VelocidadesTab              matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        UIAxesVelocidades           matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        SetCerosButton              matlab.ui.control.Button
        YactualEditField            matlab.ui.control.NumericEditField
        YactualEditFieldLabel       matlab.ui.control.Label
        XactualEditField            matlab.ui.control.NumericEditField
        XactualEditFieldLabel       matlab.ui.control.Label
        ShowYCheckBox               matlab.ui.control.CheckBox
        ShowXCheckBox               matlab.ui.control.CheckBox
        TiempoplotEditField         matlab.ui.control.NumericEditField
        TiempoplotEditFieldLabel    matlab.ui.control.Label
        LabelCopyright              matlab.ui.control.Label
        ConexinSerialLabel          matlab.ui.control.Label
        ConectarButton              matlab.ui.control.Button
        BaudRateDropDown            matlab.ui.control.DropDown
        BaudRateDropDownLabel       matlab.ui.control.Label
        PuertoDropDown              matlab.ui.control.DropDown
        PuertoDropDownLabel         matlab.ui.control.Label
        ReferenciaDropDown          matlab.ui.control.DropDown
        ReferenciaDropDownLabel     matlab.ui.control.Label
        EnviarButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        
        %control=[{q0};{q1};{q2};{s0}];
        
        
        controles;
        %
        s;          %serial
        
        % circshift(v,length(v)-1)
        % graficas
        sampleTime=0.005;
        totalSamples;
        dataTime;
        dataReference;
        dataOut;
        dataError;
        dataControl;
        dataVelocidad1;
        dataVelocidad2;

        dataPulses;
        
        counter=0;
        
        %tiempos de muestres


        %enviar Torques
        timerTorques = timer;
        Ka=1.4048;


        %trayectoria
        stepTrayectory=0.05; %0.1

        vectPos=zeros(2,5);
        vectVel=zeros(2,5);
        vectTime=1:0.1:2;
        totalSteps;

        %Movement
        Xactual=0;
        Yactual=0;
        
        dataPulsos;
        dataPosicion;
        dataVelocidad;

        %control de posicion
        tmControl=0.05;
    end
    
    methods (Access = private)
        
        function [] = setLenData(app)
            totalTime = app.TiempoplotEditField.Value;
            app.dataTime = 0:app.sampleTime*10:totalTime;
            app.totalSamples=length(app.dataTime);
            app.dataReference=zeros(1,app.totalSamples);
            app.dataOut=zeros(1,app.totalSamples);
            app.dataError=zeros(1,app.totalSamples);
            app.dataControl=zeros(1,app.totalSamples);
            app.dataPosicion=zeros(1,app.totalSamples);
            app.dataVelocidad1=zeros(1,app.totalSamples);
            app.dataVelocidad2=zeros(1,app.totalSamples);
            app.dataPulses=zeros(1,app.totalSamples);
            disp("total datos a plotear: "+ app.totalSamples)
        end
       
        
        function [] = generateTrajectory(app)
            time=app.TiempoTrEditField.Value;
            lenVectors=time/app.stepTrayectory;
            vectPoss=zeros(2,lenVectors);
            vectVell=zeros(2,lenVectors);
            Xo=app.Xactual;
            Yo=app.Yactual;
            Xf=app.XfinalTrEditField.Value;
            Yf=app.YfinalTrEditField.Value;
    
            wpts=[Xo Xf; Yo Yf];
            tvec = 0:app.stepTrayectory:time;
            item=str2double(app.TipoTrDropDown.Value);
            switch item
                case 1
                    velx=(Xf-Xo)/time;
                    vely=(Yf-Yo)/time;
                    vectPoss(1,:)=linspace(Xo,Xf,lenVectors);
                    vectPoss(2,:)=linspace(Yo,Yf,lenVectors);
                    vectVell(1,:)=repmat(velx,1,lenVectors);
                    vectVell(2,:)=repmat(vely,1,lenVectors); 
                    vectVell(:,1)=[0;0];
                    vectVell(:,end)=[0;0];
                
                case 2
                    [vectPoss, vectVell, ~, ~] = cubicpolytraj(wpts, [0 time], tvec);
    
                case 3
                    [vectPoss,vectVell,~,~,~] = trapveltraj(wpts,lenVectors);
                 
            end
            app.vectPos=vectPoss;
            app.vectVel=vectVell;
            app.totalSteps=lenVectors;
            app.vectTime=tvec;

            app.dataPulses=zeros(2,lenVectors);
            app.dataPosicion=zeros(2,lenVectors);
            app.dataVelocidad=zeros(2,lenVectors);
        end

        
        function [] = sendTrayectory(app)
            
            delete(app.timerTorques)

            app.timerTorques=timer;
            app.timerTorques.StartDelay = 3;
            app.timerTorques.TimerFcn = @app.timerTorquesFcn;
            app.timerTorques.Period=app.tmControl;
            app.timerTorques.TasksToExecute=app.totalSteps;
            app.timerTorques.ExecutionMode  = 'fixedRate'; 
            app.timerTorques.StopFcn= @stopTimerTorques;
            start(app.timerTorques)
        end

        function [] = timerTorquesFcn(app,obj,event)
            t=event.Data.time(5)*60+event.Data.time(6);
            actualStep=obj.TasksExecuted;

            torque=app.vectVel(1,actualStep);
            ref1=round(1000*torque/app.Ka);
            msg="R"+ref1+","+ref1;
            writeline(app.s,msg);
        end

        function [] = stopTimerTorques(app,~,~)
            
            msg="P";
            writeline(app.s,msg);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x', 231};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            enviarTorques(app);
        end

        % Callback function
        function CambiarcontrolButtonPushed(app, event)
            indexControl=app.ControlDropDown.Value;
            coefs=app.controles{indexControl};
            msg="C"+coefs(1)+","+coefs(2)+","+coefs(3)+","+coefs(4);
            disp(msg)
            writeline(app.s,msg);

        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            
            if app.ConectarButton.Text=="Conectar"
                
                com=app.PuertoDropDown.Value;
                baudRate=str2double(app.BaudRateDropDown.Value);
                app.s = serialport(com,baudRate);
                app.ConectarButton.Text="Desconectar";
                configureCallback(app.s,"terminator",@readSerialData)
                setLenData(app);
            
            elseif app.ConectarButton.Text=="Desconectar"
                app.s.delete;
                app.ConectarButton.Text="Conectar";
            end
            
            function readSerialData(src,~)
                %{pulses1},{pulses2}
                data = readline(src);
                data = str2double(split(data,","));
                len = app.totalSteps;
            
                %posicion
                app.dataVelocidad = circshift(app.dataVelocidad,len-1,2);
                app.dataVelocidad(1,end) = 60*data(1)/(22*5e-3*78);
                %app.dataError=app.dataReference-app.dataOut;
                app.counter=app.counter+1;
                if(app.counter==5)
                    plot(app.UIAxesPosiciones, ...
                        app.vectTime,app.dataVelocidad(1,:), ...
                        app.vectTime,app.vectVel);
%                     %plot(app.UIAxesPosiciones,app.dataTime,app.dataReference);
%                     
%                     plot(app.UIAxesVelocidades,app.dataTime,app.dataError);
%                     app.counter=0;
%                     
%                     plot(app.UIAxesControl,app.dataTime,app.dataControl);
%                     plot(app.UIAxesVelocidad,app.dataTime,app.dataVelocidad1);
                end
                
            end
            
        end

        % Drop down opening function: PuertoDropDown
        function PuertoDropDownOpening(app, event)
            puertos = split(serialportlist," ");
            app.PuertoDropDown.Items = puertos;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timerTorques)
            delete(app.timerTorques)
            delete(app)
            
        end

        % Value changed function: TiempoplotEditField
        function TiempoplotEditFieldValueChanged(app, event)
            setLenData(app);
        end

        % Button pushed function: sendTrayectoryButton
        function sendTrayectoryButtonPushed(app, event)
            generateTrajectory(app);
            sendTrayectory(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TipoDropDownLabel = uilabel(app.LeftPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoTrDropDown = uidropdown(app.LeftPanel);
            app.TipoTrDropDown.Items = {'Lineal', 'Polinomial - 3', 'Trapezoidal'};
            app.TipoTrDropDown.ItemsData = {'1', '2', '3'};
            app.TipoTrDropDown.Value = '1';
            app.XfinalEditFieldLabel = uilabel(app.LeftPanel);
            app.XfinalEditFieldLabel.Text = 'X final';
            app.XfinalTrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XfinalTrEditField.Value = 0.2;
            app.YfinalEditFieldLabel = uilabel(app.LeftPanel);
            app.YfinalEditFieldLabel.Text = 'Y final';
            app.YfinalTrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YfinalTrEditField.Value = 0.2;
            app.Panel = uipanel(app.LeftPanel);
            app.moveUpButton = uibutton(app.Panel, 'push');
            app.moveUpButton.Icon = fullfile(pathToMLAPP, 'flechaArriba.png');
            app.moveUpButton.Text = '';
            app.moveRightButton = uibutton(app.Panel, 'push');
            app.moveRightButton.Icon = fullfile(pathToMLAPP, 'flechaDerecha.png');
            app.moveRightButton.Text = '';
            app.moveLeftButton = uibutton(app.Panel, 'push');
            app.moveLeftButton.Icon = fullfile(pathToMLAPP, 'flechaIzquierda.png');
            app.moveLeftButton.Text = '';
            app.moveDownButton = uibutton(app.Panel, 'push');
            app.moveDownButton.Icon = fullfile(pathToMLAPP, 'flechaAbajo.png');
            app.moveDownButton.Text = '';
            app.stopMovementButton = uibutton(app.Panel, 'push');
            app.stopMovementButton.Icon = fullfile(pathToMLAPP, 'pausa.png');
            app.stopMovementButton.Text = '';
            app.sendTrayectoryButton = uibutton(app.Panel, 'push');
            app.sendTrayectoryButton.ButtonPushedFcn = createCallbackFcn(app, @sendTrayectoryButtonPushed, true);
            app.sendTrayectoryButton.Icon = fullfile(pathToMLAPP, 'accept.png');
            app.sendTrayectoryButton.Text = '';
            app.VelocidadmmsEditFieldLabel = uilabel(app.LeftPanel);
            app.VelocidadmmsEditFieldLabel.Text = 'Velocidad [mm/s]';
            app.VelocidadmmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoTrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoTrEditField.ValueDisplayFormat = '%.1f';
            app.TiempoTrEditField.Value = 5;
            app.ControldeRobotSCARALabel = uilabel(app.LeftPanel);
            app.ControldeRobotSCARALabel.Text = 'Control de Robot SCARA';
            app.TrayectoriaLabel = uilabel(app.LeftPanel);
            app.TrayectoriaLabel.Text = 'Trayectoria';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PosicionesTab = uitab(app.TabGroup);
            app.PosicionesTab.Title = 'Posiciones';
            app.GridLayout3 = uigridlayout(app.PosicionesTab);
            app.UIAxesPosiciones = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesPosiciones, 'Time [s]')
            ylabel(app.UIAxesPosiciones, 'Pos [mm]')
            zlabel(app.UIAxesPosiciones, 'Z')
            app.VelocidadesTab = uitab(app.TabGroup);
            app.VelocidadesTab.Title = 'Velocidades';
            app.GridLayout4 = uigridlayout(app.VelocidadesTab);
            app.UIAxesVelocidades = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesVelocidades, 'time [s]')
            ylabel(app.UIAxesVelocidades, 'Vel [mm/s]')
            zlabel(app.UIAxesVelocidades, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.PosicinTab = uitab(app.TabGroup2);
            app.PosicinTab.Title = 'Posición';
            app.GridLayout6 = uigridlayout(app.PosicinTab);
            app.UIAxesCartesian = uiaxes(app.GridLayout6);
            xlabel(app.UIAxesCartesian, 'X [mm]')
            ylabel(app.UIAxesCartesian, 'Y [mm]')
            zlabel(app.UIAxesCartesian, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.PuertoDropDownLabel = uilabel(app.RightPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.RightPanel);
            app.PuertoDropDown.Items = {'COM 9', 'COM 8'};
            app.PuertoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PuertoDropDownOpening, true);
            app.PuertoDropDown.Value = 'COM 9';
            app.BaudRateDropDownLabel = uilabel(app.RightPanel);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.RightPanel);
            app.BaudRateDropDown.Items = {'9600', '115200', '230400'};
            app.BaudRateDropDown.Value = '230400';
            app.ConectarButton = uibutton(app.RightPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ConexinSerialLabel = uilabel(app.RightPanel);
            app.ConexinSerialLabel.Text = 'Conexión Serial';
            app.LabelCopyright = uilabel(app.RightPanel);
            app.LabelCopyright.Text = {'El uso no autorizado así como la reproducción'; 'total o parcial de este contenido por cualquier '; 'persona o entidad, estará en contra de los '; 'derechos de autor. ©'};
            app.TiempoplotEditFieldLabel = uilabel(app.RightPanel);
            app.TiempoplotEditFieldLabel.Text = 'Tiempo plot';
            app.TiempoplotEditField = uieditfield(app.RightPanel, 'numeric');
            app.TiempoplotEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoplotEditFieldValueChanged, true);
            app.TiempoplotEditField.Value = 10;
            app.ShowXCheckBox = uicheckbox(app.RightPanel);
            app.ShowXCheckBox.Text = 'Show X';
            app.ShowYCheckBox = uicheckbox(app.RightPanel);
            app.ShowYCheckBox.Text = 'Show Y';
            app.XactualEditFieldLabel = uilabel(app.RightPanel);
            app.XactualEditFieldLabel.Text = 'X actual';
            app.XactualEditField = uieditfield(app.RightPanel, 'numeric');
            app.XactualEditField.Editable = 'off';
            app.YactualEditFieldLabel = uilabel(app.RightPanel);
            app.YactualEditFieldLabel.Text = 'Y actual';
            app.YactualEditField = uieditfield(app.RightPanel, 'numeric');
            app.YactualEditField.Editable = 'off';
            app.SetCerosButton = uibutton(app.RightPanel, 'push');
            app.SetCerosButton.Text = 'Set Ceros';
            app.EnviarButton = uibutton(app.UIFigure, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Icon = 'logo_mecatronica_basicoPeque.png';
            app.EnviarButton.Text = 'Enviar';
            app.ReferenciaDropDownLabel = uilabel(app.UIFigure);
            app.ReferenciaDropDownLabel.Text = 'Referencia';
            app.ReferenciaDropDown = uidropdown(app.UIFigure);
            app.ReferenciaDropDown.Items = {'Cuadrada', 'Triangular', 'Senoidal'};
            app.ReferenciaDropDown.ItemsData = [1 2 3];
            app.ReferenciaDropDown.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labTopicosAppV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end